classdef tentative < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UserInputPanel                  matlab.ui.container.Panel
        FiletypeButtonGroup             matlab.ui.container.ButtonGroup
        csvButton                       matlab.ui.control.RadioButton
        txtButton                       matlab.ui.control.RadioButton
        RunButton                       matlab.ui.control.Button
        LibraryLabel                    matlab.ui.control.Label
        CircuitLabel                    matlab.ui.control.Label
        ImportCircuitButton             matlab.ui.control.Button
        ImportlIbraryButton             matlab.ui.control.Button
        ImportownlibraryCheckBox        matlab.ui.control.CheckBox
        FailureModesResultsPanel        matlab.ui.container.Panel
        FilewasexportedLabel            matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        WeakpartsCheckBox               matlab.ui.control.CheckBox
        HomologousrecombinationCheckBox  matlab.ui.control.CheckBox
        TextAreTextArea                 matlab.ui.control.TextArea
        ExporttxtButton                 matlab.ui.control.Button
        IncompletesequenceCheckBox      matlab.ui.control.CheckBox
        UnintendedpartcreationCheckBox  matlab.ui.control.CheckBox
        IncorrectpartsorderCheckBox     matlab.ui.control.CheckBox
    end

    properties (Access = private)
        library = table2struct(readtable('552_library.csv')); % own imported library, vector of structs
        % Name, Seq, Type, Repressed_by, Produces, Strength
        circuits = {};
        file_circuit;
        dna;
        messages = {};
    end
    
    methods (Access = private)
        
        % Failure Mode 1
        function out = unintentional_parts(app)
            out = 0;
            
            % First check interactions
            produced = {};
            represses = {};
            for i = 1:length(app.circuits) % accumulating all repressed
                part = app.circuits{i};
                part_struct = app.get_struct(part);
                
                if isequal(part_struct.Type, 'CDS')
                    produced{length(produced)+1} = part_struct.Produces;
                elseif isequal(part_struct.Type, 'Promoter')
                    represses{length(represses)+1} = part_struct.Repressed_by;
                end
            end 
            if ~isempty(intersect(produced,represses))
                app.messages{length(app.messages)+1} = ['There is a promoter being repressed by' , intersect(produced,represses), 'from a CDS.'];
                out = 1;
            end
            
            % Second check other parts created
            % form big dna
            dna = '';
            for i = 1:length(app.circuits)
                part_struct = app.get_struct(app.circuits{i});
                dna = [dna,part_struct.Seq(1:end-1)];
            end
            
            for i = 1:length(app.library) % go throught each part
                % not checking for genes, better computationally and veyr rare event
                if ~isequal(app.library(i).Type,'CDS')
                    selected_seq = app.library(i).Seq;
                    for j = 1: (length(dna) - length(selected_seq))
                        % comparing pieace of dna with selected seq
                        if isequal( dna(j:length(selected_seq)), selected_seq)
                            app.messages{length(app.messages)+1} = ...
                                [app.library(i).Name, 'is being unintentded  '...
                                'created at DNA position ',num2str(j)];
                            out = 1;
                        end
                    end
                end
             end
            app.dna = dna;
        end
        
        
        % Failure Mode 2
        function out = order_check(app)
            out = 0;
            part = app.circuits{1};
            part_struct = app.get_struct(part);
            state = part_struct.Type(1); % P, R, C, T
            previous_part = part;
            for i=2:length(app.circuits)  % loop through each part
                part = app.circuits{i};
                part_struct = app.get_struct(part);
                now = part_struct.Type(1);
                
                % Checking each condition
                if state == 'P' % if previous promoter
                    if now == 'C' || now == 'T' % cant be gene or temrinator
                        out = 1;
                        app.messages{length(app.messages)+1} = ...
                            ['Promoter ', previous_part ,' has the ',...
                            part_struct.Type, part,'next to it.'];
                    end
                elseif state == 'R' % if previous rbs
                    if now == 'T' %cant be terminator
                        out = 1;
                        app.messages{length(app.messages)+1} = ...
                            ['RBS ', previous_part ,' has the ',...
                            part_struct.Type, part,'next to it.'];
                    end               
                elseif state == 'T' %if previois terminator
                    if now == 'R' || now == 'C' %cant be rbs or gene
                        out = 1;
                        app.messages{length(app.messages)+1} = ...
                            ['Terminator ', previous_part ,' has the ',...
                            part_struct.Type, part,'next to it.'];
                    end
                else
                    out = 1;
                    app.messages{length(app.messages)+1} = [part ,' is of an undentifiable type.' ];
                end
                
                % updating variables
                previous_part = part;
                state = now;
            end
        end
        
        
        % Failure Mode 3
        function out = letters_3(app)
            % Checks if all parts in the circuit have DNA sequence that is
            % divided by 3.
            out = 0;
            for i=1:length(app.circuits)  % loop through each part
                part = app.circuits{i};
                part_struct = app.get_struct(part);
                % checks if CDS DNA length dividisible by 3
                if isequal(part_struct.Type,'CDS') && rem(length(part_struct.Seq),3) ~= 0
                    app.messages{length(app.messages)+1} = ['CDS ',part ,' has length not divisible by 3 .'];
                    out = 1;
                end
            end
        end
        
        
        % Failure Mode 4
        function out = recombinase_check2(app)
%             [repwarn,dupParts,dupind]
        %Checks for duplicate parts and warns about possible homologous
        %recombination for everything in between. 
        %format: recombinase_check(circuit) where circuit is cell array of parts in
        %order. Outputs warning text, list of duplicate part, and duplicate part
        %indices. 
            circ = app.circuits;
            [C,~,idc] = unique(circ,'stable');
            N = histcounts(idc,numel(C));
            dupParts = C(N>1);
            dupind = arrayfun(@(x) find(idc==x),find(N>1),'UniformOutput',false);
            out = 0;
            if ~isempty(dupParts)
                out = 1;
%                 repwarn = strings;
                for i =1:length(dupParts)
                    warn = sprintf('Duplicate part %s in positions: ', dupParts{i});
                    indstr = sprintf('%d ',dupind{i});
%                     repwarn = repwarn+warn+indstr+newline;
                    app.messages{length(app.messages)+1} = [warn,indstr];
                end
%                 repwarn = repwarn + 'Homologous recombination may occur, causing deletion of all nucleotides between duplicate parts.';
            end
        end
        
        
        % Failure Mode 5
        function out = weak_check(app)
            % Checks if all terminators in the circuit are not weak
            out = 0;
            for i=1:length(app.circuits)  % loop through each part
                part = app.circuits{i};
                part_struct = app.get_struct(part);
                % checks if CDS DNA length dividisible by 3
                if ~isequal(part_struct.Type,'CDS') && ...
                        isequal(part_struct.Strength,'Weak')
                    app.messages{length(app.messages)+1} = ...
                        [part_struct.Type,' ',part ,' is weak.' ];
                    out = 1;
                end
            end
        end

        
        % Checking if new library has only ATCG
        function out = lettercheck(app)
            plib = app.library;
            circ = app.circuits;
        %checks that only letters a,t,c,g are present. 
        %takes part library and circuit as inputs
            partind = ismember({plib.Name},circ);
            partseq = {plib(partind).Seq};
            nuc = {'A' 'T' 'C' 'G'};
            num = 0;
            for i = 1:4
                L = strfind([partseq{:}],nuc{i});
                num = num + length(L);
            end
            if num == numel([partseq{:}])
                out = 0;
            else 
                out = 1;
                app.messages{length(app.messages)+1} = ['Sequence has nucleotides bases outside of A, T, C, G.'];
            end
        end
    
        % get struct from 
        function s = get_struct(app,part)
            % Given a character vector of part, returns structure from library
            index = 1;
            for i = 1:length(app.library)
                if isequal(app.library(i).Name, part)
                    index = i;
                    break
                end
            end
          
            s = app.library(index); 
            
              
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function run_fn(app, event)
            % Failure mode 1
            app.TextAreTextArea.Value = 'Checking for Unintentional Parts...';
            pause(2); 
            if app.unintentional_parts
                app.UnintendedpartcreationCheckBox.Value = logical(1);
            end

            % Failure mode 2
            app.TextAreTextArea.Value = 'Checking for Incorrect Parts Order...';
            pause(2); 
            if app.order_check
                app.IncorrectpartsorderCheckBox.Value = logical(1);
            end

            % Failure mode 3
            app.TextAreTextArea.Value = 'Checking for Incomplete Sequences...';
            pause(2); 
            if app.letters_3
                app.IncompletesequenceCheckBox.Value = logical(1);
            end
            if app.lettercheck
                app.IncompletesequenceCheckBox.Value = logical(1);
            end
            
            
            % Failure mode 4
            app.TextAreTextArea.Value = 'Checking for Homologous Recombination...';
            pause(2); 
            if app.recombinase_check2
                app.HomologousrecombinationCheckBox.Value = logical(1);
            end
            
            % Failure mode 5
            app.TextAreTextArea.Value = 'Checking for Weak Parts...';
            pause(2); 
            if app.weak_check
                app.WeakpartsCheckBox.Value = logical(1);
            end


            if isempty(app.messages)
                app.TextAreTextArea.Value = 'Design has no flaws!';
            else
                app.TextAreTextArea.Value = app.messages;
            end


        end

        % Button pushed function: ImportlIbraryButton
        function library_fn(app, event)

            [file,path] = uigetfile('*.csv');
            
            if file ~= 0
                library_new = table2struct(readtable(file));
                fieldnames_fine = 1;
                fields = fieldnames(library_new);
                % Checking if  all fields match
                a = {'Name','Seq','Type','Repressed_by','Produces','Strength'};
                b = a'; 
                for i=1:length(b)
                    if length(b) ~= length(fields) || ~isequal(fields{i}, b{i})
                        fieldnames_fine = 0;
                        break
                    end
                end
                if fieldnames_fine
                 
                    len_original = length(app.library);
                    len_new = length(library_new);
                    for i = 1 : len_new
                        app.library(len_original+i) = library_new(i);
                    end
                    
                    app.LibraryLabel.Visible = logical(1) ;
                    app.LibraryLabel.Enable = logical(1)  ;
                    app.LibraryLabel.Text = [file,' imported!'] ;
                else
                    app.LibraryLabel.Visible = logical(1) ;
                    app.LibraryLabel.Enable = logical(1)  ;
                    app.LibraryLabel.Text = ['Library wasnt imported.'] ;
                end 
            end
            
        end

        % Button pushed function: ImportCircuitButton
        function circuit_fn(app, event)

              if app.csvButton.Value
                  type = '*.csv';
              else
                  type = '*.txt';
              end
            [file,~,ind] = uigetfile(type,'File Selector');
            app.file_circuit = file(1:end-4);
            if file ~= 0
                fid = fopen(file);
                app.CircuitLabel.Text = ['Getting sequence...'] ;
                aline = fgetl(fid);

                app.circuits = split(aline,',');
                app.CircuitLabel.Text = [file,' imported!'] ;
                fclose(fid);
            end
           
        end

        % Value changed function: ImportownlibraryCheckBox
        function library_yes(app, event)
            value = app.ImportownlibraryCheckBox.Value;
            app.ImportlIbraryButton.Visible = value;
            app.ImportlIbraryButton.Enable = value;
        end

        % Value changed function: UnintendedpartcreationCheckBox
        function fm_1(app, event)
            value = app.UnintendedpartcreationCheckBox.Value;
            app.UnintendedpartcreationCheckBox.Value = ~value;
        end

        % Value changed function: IncorrectpartsorderCheckBox
        function fm_2(app, event)
            value = app.IncorrectpartsorderCheckBox.Value;
            app.IncorrectpartsorderCheckBox.Value = ~value;
        end

        % Value changed function: IncompletesequenceCheckBox
        function fm_3(app, event)
            value = app.IncompletesequenceCheckBox.Value;
            app.IncompletesequenceCheckBox.Value = ~value;
        end

        % Value changed function: HomologousrecombinationCheckBox
        function fm_4(app, event)
            value = app.HomologousrecombinationCheckBox.Value;
            app.HomologousrecombinationCheckBox.Value = ~value;
        end

        % Value changed function: WeakpartsCheckBox
        function fm_5(app, event)
            value = app.WeakpartsCheckBox.Value;
            app.WeakpartsCheckBox.Value = ~value;
        end

        % Button pushed function: ExporttxtButton
        function export_txt(app, event)
            if ~isempty(app.messages)
                if length(app.TextArea.Value) == 1
                    filename = [ app.file_circuit, '_warnings'] ;
%                     app.FilewasexportedLabel.Text = 'Bruh';
                else
                    filename = app.TextArea.Value{1};
                end
                
                fid = fopen([filename,'.txt' ],'w');
                sentence = '';
                for i = 1:length(app.messages)
                    sentence = [sentence, app.messages{i},'\n'];
                end
                fprintf(fid, sentence);
                fclose(fid);
                app.FilewasexportedLabel.Visible = logical(1);
%                 app.FilewasexportedLabel.Text = num2str(length(app.TextArea.Value));
            else
                app.FilewasexportedLabel.Visible = logical(1);
                app.FilewasexportedLabel.Text = 'File not exported';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FailureModesResultsPanel = uipanel(app.UIFigure);
            app.FailureModesResultsPanel.Title = 'Failure Modes Results';
            app.IncorrectpartsorderCheckBox = uicheckbox(app.FailureModesResultsPanel);
            app.IncorrectpartsorderCheckBox.ValueChangedFcn = createCallbackFcn(app, @fm_2, true);
            app.IncorrectpartsorderCheckBox.Text = ' Incorrect parts order';
            app.UnintendedpartcreationCheckBox = uicheckbox(app.FailureModesResultsPanel);
            app.UnintendedpartcreationCheckBox.ValueChangedFcn = createCallbackFcn(app, @fm_1, true);
            app.UnintendedpartcreationCheckBox.Text = ' Unintended part creation';
            app.IncompletesequenceCheckBox = uicheckbox(app.FailureModesResultsPanel);
            app.IncompletesequenceCheckBox.ValueChangedFcn = createCallbackFcn(app, @fm_3, true);
            app.IncompletesequenceCheckBox.Text = ' Incomplete sequence';
            app.ExporttxtButton = uibutton(app.FailureModesResultsPanel, 'push');
            app.ExporttxtButton.ButtonPushedFcn = createCallbackFcn(app, @export_txt, true);
            app.ExporttxtButton.Text = 'Export .txt';
            app.TextAreTextArea = uitextarea(app.FailureModesResultsPanel);
            app.TextAreTextArea.Editable = 'off';
            app.TextAreTextArea.Value = {'Warnings will appear here!'; ''};
            app.HomologousrecombinationCheckBox = uicheckbox(app.FailureModesResultsPanel);
            app.HomologousrecombinationCheckBox.ValueChangedFcn = createCallbackFcn(app, @fm_4, true);
            app.HomologousrecombinationCheckBox.Text = ' Homologous recombination ';
            app.WeakpartsCheckBox = uicheckbox(app.FailureModesResultsPanel);
            app.WeakpartsCheckBox.ValueChangedFcn = createCallbackFcn(app, @fm_5, true);
            app.WeakpartsCheckBox.Text = ' Weak parts';
            app.TextArea = uitextarea(app.FailureModesResultsPanel);
            app.TextArea.Placeholder = 'Filename (optional)';
            app.FilewasexportedLabel = uilabel(app.FailureModesResultsPanel);
            app.FilewasexportedLabel.Visible = 'off';
            app.FilewasexportedLabel.Text = 'File was exported!';
            app.UserInputPanel = uipanel(app.UIFigure);
            app.UserInputPanel.Title = 'User Input';
            app.ImportownlibraryCheckBox = uicheckbox(app.UserInputPanel);
            app.ImportownlibraryCheckBox.ValueChangedFcn = createCallbackFcn(app, @library_yes, true);
            app.ImportownlibraryCheckBox.Text = 'Import own library';
            app.ImportlIbraryButton = uibutton(app.UserInputPanel, 'push');
            app.ImportlIbraryButton.ButtonPushedFcn = createCallbackFcn(app, @library_fn, true);
            app.ImportlIbraryButton.Enable = 'off';
            app.ImportlIbraryButton.Visible = 'off';
            app.ImportlIbraryButton.Text = 'Import lIbrary';
            app.ImportCircuitButton = uibutton(app.UserInputPanel, 'push');
            app.ImportCircuitButton.ButtonPushedFcn = createCallbackFcn(app, @circuit_fn, true);
            app.ImportCircuitButton.Text = 'Import Circuit';
            app.CircuitLabel = uilabel(app.UserInputPanel);
            app.CircuitLabel.Text = 'Circuit not imported';
            app.LibraryLabel = uilabel(app.UserInputPanel);
            app.LibraryLabel.Enable = 'off';
            app.LibraryLabel.Visible = 'off';
            app.LibraryLabel.Text = 'Library not imported';
            app.RunButton = uibutton(app.UserInputPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @run_fn, true);
            app.RunButton.Text = 'Run !';
            app.FiletypeButtonGroup = uibuttongroup(app.UserInputPanel);
            app.FiletypeButtonGroup.Title = 'File type';
            app.txtButton = uiradiobutton(app.FiletypeButtonGroup);
            app.txtButton.Text = '.txt';
            app.txtButton.Value = true;
            app.csvButton = uiradiobutton(app.FiletypeButtonGroup);
            app.csvButton.Text = '.csv';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tentative

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end